classdef Meta_Terminal_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetaTerminal                  matlab.ui.Figure
        AngleAxes                     matlab.ui.control.UIAxes
        VelocityAxes                  matlab.ui.control.UIAxes
        CurrentAxes                   matlab.ui.control.UIAxes
        ConnectButton                 matlab.ui.control.Button
        DisconnectButton              matlab.ui.control.Button
        SendText                      matlab.ui.control.EditField
        SendButton                    matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        ClearDataButton               matlab.ui.control.Button
        AngleLabel                    matlab.ui.control.Label
        CurrentLabel                  matlab.ui.control.Label
        TargetLabel                   matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        CurrentLabel_2                matlab.ui.control.Label
        TargetLabel_2                 matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        VelocityLabel                 matlab.ui.control.Label
        CurrentLabel_3                matlab.ui.control.Label
        TargetLabel_3                 matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        CurrentLabel_4                matlab.ui.control.Label
        DisplayButtonGroup            matlab.ui.container.ButtonGroup
        YawButton                     matlab.ui.control.RadioButton
        PitchButton                   matlab.ui.control.RadioButton
        OutputTextArea                matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        CommonTab                     matlab.ui.container.Tab
        statsButton                   matlab.ui.control.Button
        threadsButton                 matlab.ui.control.Button
        memButton                     matlab.ui.control.Button
        systimeButton                 matlab.ui.control.Button
        helloButton                   matlab.ui.control.Button
        GimbalTab                     matlab.ui.container.Tab
        YawCheckBox                   matlab.ui.control.CheckBox
        PitchCheckBox                 matlab.ui.control.CheckBox
        EnableButton                  matlab.ui.control.Button
        YawTargetCurrentEditField     matlab.ui.control.EditField
        PitchTargetCurrentEditField   matlab.ui.control.EditField
        SetCurrentButton              matlab.ui.control.Button
        SetVelocityButton             matlab.ui.control.Button
        YawTargetVelocityEditField    matlab.ui.control.EditField
        PitchTargetVelocityEditField  matlab.ui.control.EditField
        SetAngleButton                matlab.ui.control.Button
        YawTargetAngleEditField       matlab.ui.control.EditField
        PitchTargetAngleEditField     matlab.ui.control.EditField
        SetFrontAngleButton           matlab.ui.control.Button
        PIDAdjustmentPanel            matlab.ui.container.Panel
        MotorButtonGroup              matlab.ui.container.ButtonGroup
        PIDYawButton                  matlab.ui.control.RadioButton
        PIDPitchButton                matlab.ui.control.RadioButton
        PIDButtonGroup                matlab.ui.container.ButtonGroup
        angle_to_vButton              matlab.ui.control.RadioButton
        v_to_iButton                  matlab.ui.control.RadioButton
        KpEditField                   matlab.ui.control.EditField
        KiEditField                   matlab.ui.control.EditField
        KdEditField                   matlab.ui.control.EditField
        i_limitEditField              matlab.ui.control.EditField
        out_limitEditField            matlab.ui.control.EditField
        KpLabel                       matlab.ui.control.Label
        KiLabel                       matlab.ui.control.Label
        KdLabel                       matlab.ui.control.Label
        i_limitLabel                  matlab.ui.control.Label
        out_limitLabel                matlab.ui.control.Label
        SetPIDButton                  matlab.ui.control.Button
        EchoPIDButton                 matlab.ui.control.Button
        FreezeButton                  matlab.ui.control.StateButton
        PortDropDownLabel             matlab.ui.control.Label
        PortDropDown                  matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        scom  % serial port
        gimbal_yaw_time
        gimbal_yaw_actual_angle
        gimbal_yaw_target_angle
        gimbal_yaw_actual_velocity
        gimbal_yaw_target_velocity
        gimbal_yaw_actual_current
        gimbal_yaw_target_current
        gimbal_pitch_time
        gimbal_pitch_actual_angle
        gimbal_pitch_target_angle
        gimbal_pitch_actual_velocity
        gimbal_pitch_target_velocity
        gimbal_pitch_actual_current
        gimbal_pitch_target_current
        
        pid_params
    end
    
    methods (Access = private)
        
        % Plot variables to axes and set labels
        function plotToAxes(~, target_axes, time_values, actual_values, target_values, actual_label, target_label)
            
            cla(target_axes,"reset");
            plot(time_values, actual_values, "r.-", "linewidth", 2, "Parent", target_axes);
            hold(target_axes, 'on');
            plot(time_values, target_values, "b.-", "linewidth", 2, "Parent", target_axes);
            
            actual_label.Text = num2str(actual_values(end));
            target_label.Text = num2str(target_values(end));
            
        end
        
        % Print message to output
        function printToOutput(app, str)
            app.OutputTextArea.Value = [app.OutputTextArea.Value; str];
        end
        
        % PIDPitchButton.Value = 0 -- Yaw is selected, motor id = 1
        % PIDPitchButton.Value = 1 -- Pitch is selected, motor id = 2
        % Similar for i_to_vButton
        function results = pidSelectedMotor(app)
            results = app.PIDPitchButton.Value + 1;
        end
        
        % PIDPitchButton.Value = 0 -- Yaw is selected, motor id = 1 for MATLAB, 0 for program
        % PIDPitchButton.Value = 1 -- Pitch is selected, motor id = 2 for MATLAB, 1 for program
        % Similar for i_to_vButton
        function results = pidSelectedPID(app)
            results = app.v_to_iButton.Value + 1;
        end
    end
    
    methods (Access = public)
        
        function s_rx_callback(app, ~, ~)
            
            str = fgetl(app.scom);
            p = strsplit(str, ",");
            p1 = char(p(1));
            if strlength(p1) >=3 && p1(1) == "!" % feedback data
                if p1(2) == "g" % gimbal
                    if p1(3) == "y" % yaw
                        
                        % Update data
                        app.gimbal_yaw_time(end+1) = str2double(char(p(2)));
                        app.gimbal_yaw_actual_angle(end+1) = str2double(char(p(3)));
                        app.gimbal_yaw_target_angle(end+1) = str2double(char(p(4)));
                        app.gimbal_yaw_actual_velocity(end+1) = str2double(char(p(5)));
                        app.gimbal_yaw_target_velocity(end+1) = str2double(char(p(6)));
                        app.gimbal_yaw_actual_current(end+1) = str2double(char(p(7)));
                        app.gimbal_yaw_target_current(end+1) = str2double(char(p(8)));
                        
                        % Updata images and labels
                        if app.YawButton.Value == 1 && app.FreezeButton.Value == 0
                            app.plotToAxes(app.AngleAxes, app.gimbal_yaw_time, app.gimbal_yaw_actual_angle, app.gimbal_yaw_target_angle, app.Label, app.Label_2);
                            app.plotToAxes(app.VelocityAxes, app.gimbal_yaw_time, app.gimbal_yaw_actual_velocity, app.gimbal_yaw_target_velocity, app.Label_3, app.Label_4);
                            app.plotToAxes(app.CurrentAxes, app.gimbal_yaw_time, app.gimbal_yaw_actual_current, app.gimbal_yaw_target_current, app.Label_5, app.Label_6);
                        end
                    elseif p1(3) == "p" % pitch
                        
                        % Update data
                        app.gimbal_pitch_time(end+1) = str2double(char(p(2)));
                        app.gimbal_pitch_actual_angle(end+1) = str2double(char(p(3)));
                        app.gimbal_pitch_target_angle(end+1) = str2double(char(p(4)));
                        app.gimbal_pitch_actual_velocity(end+1) = str2double(char(p(5)));
                        app.gimbal_pitch_target_velocity(end+1) = str2double(char(p(6)));
                        app.gimbal_pitch_actual_current(end+1) = str2double(char(p(7)));
                        app.gimbal_pitch_target_current(end+1) = str2double(char(p(8)));
                        
                        % Updata images and labels
                        if app.PitchButton.Value == 1 && app.FreezeButton.Value == 0
                            app.plotToAxes(app.AngleAxes, app.gimbal_pitch_time, app.gimbal_pitch_actual_angle, app.gimbal_pitch_target_angle, app.Label, app.Label_2);
                            app.plotToAxes(app.VelocityAxes, app.gimbal_pitch_time, app.gimbal_pitch_actual_velocity, app.gimbal_pitch_target_velocity, app.Label_3, app.Label_4);
                            app.plotToAxes(app.CurrentAxes, app.gimbal_pitch_time, app.gimbal_pitch_actual_current, app.gimbal_pitch_target_current, app.Label_5, app.Label_6);
                        end
                    end
                end
            else % normal data
                app.printToOutput (str);
            end
        end
        
        function sendString(app, str)
            fprintf("Sending: %s\n", str);
            fprintf(app.scom, "%s\n", str);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function Meta_Terminal_OpeningFcn(app)
            ClearDataButtonPushed(app, 0);
            app.pid_params = {{zeros(5),zeros(5)},{zeros(5),zeros(5)}};
        end

        % Button pushed function: ClearButton
        function ClearButton_Callback(app, event)
            app.OutputTextArea.Value = [""];
        end

        % Button pushed function: ConnectButton
        function ConnectButton_Callback(app, event)
            delete(instrfind)  % close all open connection
            
            % Setup the serial port
            app.scom = serial(app.PortDropDown.Value,"BaudRate",115200);
            app.scom.BaudRate = 115200;
            app.scom.BytesAvailableFcnMode = "terminator";
            app.scom.Terminator = "CR/LF";
            app.scom.BytesAvailableFcn = @app.s_rx_callback;
            fopen(app.scom);
            
            app.printToOutput ("[Connected]");
            fprintf("[Connected]");
        end

        % Button pushed function: SendButton
        function SendButton_Callback(app, event)
            sendString(app, app.SendText.Value);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButton_Callback(app, event)
            delete(instrfind);  % close all open connection
            app.printToOutput ("[Disconnected]");
            fprintf("[Disconnected]");
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            
            % Clear the data
            app.gimbal_yaw_time = [];
            app.gimbal_yaw_actual_angle = [];
            app.gimbal_yaw_target_angle =[];
            app.gimbal_yaw_actual_velocity = [];
            app.gimbal_yaw_target_velocity = [];
            app.gimbal_yaw_actual_current = [];
            app.gimbal_yaw_target_current = [];
            
            app.gimbal_pitch_time = [];
            app.gimbal_pitch_actual_angle = [];
            app.gimbal_pitch_target_angle =[];
            app.gimbal_pitch_actual_velocity = [];
            app.gimbal_pitch_target_velocity = [];
            app.gimbal_pitch_actual_current = [];
            app.gimbal_pitch_target_current = [];
            
            % Clear the image
            cla(app.AngleAxes, "reset");
            cla(app.VelocityAxes, "reset");
            cla(app.CurrentAxes, "reset");
        end

        % Button pushed function: statsButton
        function statsButtonPushed(app, event)
            app.sendString("stats");
        end

        % Button pushed function: threadsButton
        function threadsButtonPushed(app, event)
            app.sendString("threads");
        end

        % Button pushed function: memButton
        function memButtonPushed(app, event)
            app.sendString("mem");
        end

        % Button pushed function: systimeButton
        function systimeButtonPushed(app, event)
            app.sendString("systime");
        end

        % Button pushed function: EnableButton
        function EnableButtonPushed(app, event)
            app.sendString(sprintf("g_enable %d %d", app.YawCheckBox.Value, app.PitchCheckBox.Value));
        end

        % Button pushed function: SetFrontAngleButton
        function SetFrontAngleButtonPushed(app, event)
            app.sendString("g_fix");
        end

        % Button pushed function: SetCurrentButton
        function SetCurrentButtonPushed(app, event)
            app.sendString(sprintf("g_set %d %d", str2double(app.YawTargetCurrentEditField.Value), str2double(app.PitchTargetCurrentEditField.Value)));
        end

        % Close request function: MetaTerminal
        function MetaTerminalCloseRequest(app, event)
            delete(app)
            delete(instrfind);  % close all open connection
            fprintf("[Disconnect All]");
        end

        % Button pushed function: SetVelocityButton
        function SetVelocityButtonPushed(app, event)
            app.sendString(sprintf("g_set_v %f %f", str2double(app.YawTargetVelocityEditField.Value), str2double(app.PitchTargetVelocityEditField.Value)));
        end

        % Button pushed function: SetAngleButton
        function SetAngleButtonPushed(app, event)
            app.sendString(sprintf("g_set_angle %f %f", str2double(app.YawTargetAngleEditField.Value), str2double(app.PitchTargetAngleEditField.Value)));
        end

        % Button pushed function: EchoPIDButton
        function EchoPIDButtonPushed(app, event)
            app.sendString("g_echo_params");
        end

        % Selection changed function: MotorButtonGroup, PIDButtonGroup
        function MotorButtonGroupSelectionChanged(app, event)
            app.KpEditField.Value = num2str(app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(1));
            app.KiEditField.Value = num2str(app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(2));
            app.KdEditField.Value = num2str(app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(3));
            app.i_limitEditField.Value = num2str(app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(4));
            app.out_limitEditField.Value = num2str(app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(5));
        end

        % Button pushed function: SetPIDButton
        function SetPIDButtonPushed(app, event)
            app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(1) = str2double(app.KpEditField.Value);
            app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(2) = str2double(app.KiEditField.Value);
            app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(3) = str2double(app.KdEditField.Value);
            app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(4) = str2double(app.i_limitEditField.Value);
            app.pid_params{app.pidSelectedMotor()}{app.pidSelectedPID()}(5) = str2double(app.out_limitEditField.Value);
            app.printToOutput("[Params saved]");
            app.sendString(sprintf("g_set_params %d %d %f %f %f %f %f", ...
                app.pidSelectedMotor() - 1, app.pidSelectedPID() - 1, ...
                str2double(app.KpEditField.Value), str2double(app.KiEditField.Value), str2double(app.KdEditField.Value), ...
                str2double(app.i_limitEditField.Value), str2double(app.out_limitEditField.Value)));
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            if app.YawButton.Value == 1
                app.plotToAxes(app.AngleAxes, app.gimbal_yaw_time, app.gimbal_yaw_actual_angle, app.gimbal_yaw_target_angle, app.Label, app.Label_2);
                app.plotToAxes(app.VelocityAxes, app.gimbal_yaw_time, app.gimbal_yaw_actual_velocity, app.gimbal_yaw_target_velocity, app.Label_3, app.Label_4);
                app.plotToAxes(app.CurrentAxes, app.gimbal_yaw_time, app.gimbal_yaw_actual_current, app.gimbal_yaw_target_current, app.Label_5, app.Label_6);
            elseif app.PitchButton.Value == 1
                app.plotToAxes(app.AngleAxes, app.gimbal_pitch_time, app.gimbal_pitch_actual_angle, app.gimbal_pitch_target_angle, app.Label, app.Label_2);
                app.plotToAxes(app.VelocityAxes, app.gimbal_pitch_time, app.gimbal_pitch_actual_velocity, app.gimbal_pitch_target_velocity, app.Label_3, app.Label_4);
                app.plotToAxes(app.CurrentAxes, app.gimbal_pitch_time, app.gimbal_pitch_actual_current, app.gimbal_pitch_target_current, app.Label_5, app.Label_6);
            end
        end

        % Button pushed function: helloButton
        function helloButtonPushed(app, event)
            app.sendString("hello");
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetaTerminal = uifigure;
            app.MetaTerminal.Name = 'Meta Terminal';
            app.MetaTerminal.CloseRequestFcn = createCallbackFcn(app, @MetaTerminalCloseRequest, true);
            app.AngleAxes = uiaxes(app.MetaTerminal);
            app.VelocityAxes = uiaxes(app.MetaTerminal);
            app.CurrentAxes = uiaxes(app.MetaTerminal);
            app.ConnectButton = uibutton(app.MetaTerminal, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_Callback, true);
            app.ConnectButton.Text = 'Connnect';
            app.DisconnectButton = uibutton(app.MetaTerminal, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_Callback, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.SendText = uieditfield(app.MetaTerminal, 'text');
            app.SendButton = uibutton(app.MetaTerminal, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButton_Callback, true);
            app.SendButton.Text = 'Send';
            app.ClearButton = uibutton(app.MetaTerminal, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_Callback, true);
            app.ClearButton.Text = 'Clear Output';
            app.ClearDataButton = uibutton(app.MetaTerminal, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.AngleLabel = uilabel(app.MetaTerminal);
            app.AngleLabel.Text = 'Angle';
            app.CurrentLabel = uilabel(app.MetaTerminal);
            app.CurrentLabel.Text = 'Current:';
            app.TargetLabel = uilabel(app.MetaTerminal);
            app.TargetLabel.Text = 'Target:';
            app.Label = uilabel(app.MetaTerminal);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.MetaTerminal);
            app.Label_2.Text = '0';
            app.CurrentLabel_2 = uilabel(app.MetaTerminal);
            app.CurrentLabel_2.Text = 'Current:';
            app.TargetLabel_2 = uilabel(app.MetaTerminal);
            app.TargetLabel_2.Text = 'Target:';
            app.Label_3 = uilabel(app.MetaTerminal);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.MetaTerminal);
            app.Label_4.Text = '0';
            app.VelocityLabel = uilabel(app.MetaTerminal);
            app.VelocityLabel.Text = 'Velocity';
            app.CurrentLabel_3 = uilabel(app.MetaTerminal);
            app.CurrentLabel_3.Text = 'Current:';
            app.TargetLabel_3 = uilabel(app.MetaTerminal);
            app.TargetLabel_3.Text = 'Target:';
            app.Label_5 = uilabel(app.MetaTerminal);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.MetaTerminal);
            app.Label_6.Text = '0';
            app.CurrentLabel_4 = uilabel(app.MetaTerminal);
            app.CurrentLabel_4.Text = 'Current';
            app.DisplayButtonGroup = uibuttongroup(app.MetaTerminal);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.YawButton = uiradiobutton(app.DisplayButtonGroup);
            app.YawButton.Text = 'Yaw';
            app.YawButton.Value = true;
            app.PitchButton = uiradiobutton(app.DisplayButtonGroup);
            app.PitchButton.Text = 'Pitch';
            app.OutputTextArea = uitextarea(app.MetaTerminal);
            app.TabGroup = uitabgroup(app.MetaTerminal);
            app.CommonTab = uitab(app.TabGroup);
            app.CommonTab.Title = 'Common';
            app.statsButton = uibutton(app.CommonTab, 'push');
            app.statsButton.ButtonPushedFcn = createCallbackFcn(app, @statsButtonPushed, true);
            app.statsButton.Text = 'stats';
            app.threadsButton = uibutton(app.CommonTab, 'push');
            app.threadsButton.ButtonPushedFcn = createCallbackFcn(app, @threadsButtonPushed, true);
            app.threadsButton.Text = 'threads';
            app.memButton = uibutton(app.CommonTab, 'push');
            app.memButton.ButtonPushedFcn = createCallbackFcn(app, @memButtonPushed, true);
            app.memButton.Text = 'mem';
            app.systimeButton = uibutton(app.CommonTab, 'push');
            app.systimeButton.ButtonPushedFcn = createCallbackFcn(app, @systimeButtonPushed, true);
            app.systimeButton.Text = 'systime';
            app.helloButton = uibutton(app.CommonTab, 'push');
            app.helloButton.ButtonPushedFcn = createCallbackFcn(app, @helloButtonPushed, true);
            app.helloButton.Text = 'hello';
            app.GimbalTab = uitab(app.TabGroup);
            app.GimbalTab.Title = 'Gimbal';
            app.YawCheckBox = uicheckbox(app.GimbalTab);
            app.YawCheckBox.Text = 'Yaw';
            app.PitchCheckBox = uicheckbox(app.GimbalTab);
            app.PitchCheckBox.Text = 'Pitch';
            app.EnableButton = uibutton(app.GimbalTab, 'push');
            app.EnableButton.ButtonPushedFcn = createCallbackFcn(app, @EnableButtonPushed, true);
            app.EnableButton.Text = 'Enable';
            app.YawTargetCurrentEditField = uieditfield(app.GimbalTab, 'text');
            app.YawTargetCurrentEditField.Value = '0';
            app.PitchTargetCurrentEditField = uieditfield(app.GimbalTab, 'text');
            app.PitchTargetCurrentEditField.Value = '0';
            app.SetCurrentButton = uibutton(app.GimbalTab, 'push');
            app.SetCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @SetCurrentButtonPushed, true);
            app.SetCurrentButton.Text = 'Set Current';
            app.SetVelocityButton = uibutton(app.GimbalTab, 'push');
            app.SetVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @SetVelocityButtonPushed, true);
            app.SetVelocityButton.Text = 'Set Velocity';
            app.YawTargetVelocityEditField = uieditfield(app.GimbalTab, 'text');
            app.YawTargetVelocityEditField.Value = '0';
            app.PitchTargetVelocityEditField = uieditfield(app.GimbalTab, 'text');
            app.PitchTargetVelocityEditField.Value = '0';
            app.SetAngleButton = uibutton(app.GimbalTab, 'push');
            app.SetAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SetAngleButtonPushed, true);
            app.SetAngleButton.Text = 'Set Angle';
            app.YawTargetAngleEditField = uieditfield(app.GimbalTab, 'text');
            app.YawTargetAngleEditField.Value = '0';
            app.PitchTargetAngleEditField = uieditfield(app.GimbalTab, 'text');
            app.PitchTargetAngleEditField.Value = '0';
            app.SetFrontAngleButton = uibutton(app.GimbalTab, 'push');
            app.SetFrontAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SetFrontAngleButtonPushed, true);
            app.SetFrontAngleButton.Text = 'Set Front Angle';
            app.PIDAdjustmentPanel = uipanel(app.GimbalTab);
            app.PIDAdjustmentPanel.Title = 'PID Adjustment';
            app.MotorButtonGroup = uibuttongroup(app.PIDAdjustmentPanel);
            app.MotorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MotorButtonGroupSelectionChanged, true);
            app.MotorButtonGroup.Title = 'Motor';
            app.PIDYawButton = uiradiobutton(app.MotorButtonGroup);
            app.PIDYawButton.Text = 'Yaw';
            app.PIDYawButton.Value = true;
            app.PIDPitchButton = uiradiobutton(app.MotorButtonGroup);
            app.PIDPitchButton.Text = 'Pitch';
            app.PIDButtonGroup = uibuttongroup(app.PIDAdjustmentPanel);
            app.PIDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MotorButtonGroupSelectionChanged, true);
            app.PIDButtonGroup.Title = 'PID';
            app.angle_to_vButton = uiradiobutton(app.PIDButtonGroup);
            app.angle_to_vButton.Text = 'angle_to_v';
            app.angle_to_vButton.Value = true;
            app.v_to_iButton = uiradiobutton(app.PIDButtonGroup);
            app.v_to_iButton.Text = 'v_to_i';
            app.KpEditField = uieditfield(app.PIDAdjustmentPanel, 'text');
            app.KpEditField.Value = '0';
            app.KiEditField = uieditfield(app.PIDAdjustmentPanel, 'text');
            app.KiEditField.Value = '0';
            app.KdEditField = uieditfield(app.PIDAdjustmentPanel, 'text');
            app.KdEditField.Value = '0';
            app.i_limitEditField = uieditfield(app.PIDAdjustmentPanel, 'text');
            app.i_limitEditField.Value = '0';
            app.out_limitEditField = uieditfield(app.PIDAdjustmentPanel, 'text');
            app.out_limitEditField.Value = '0';
            app.KpLabel = uilabel(app.PIDAdjustmentPanel);
            app.KpLabel.Text = 'Kp';
            app.KiLabel = uilabel(app.PIDAdjustmentPanel);
            app.KiLabel.Text = 'Ki';
            app.KdLabel = uilabel(app.PIDAdjustmentPanel);
            app.KdLabel.Text = 'Kd';
            app.i_limitLabel = uilabel(app.PIDAdjustmentPanel);
            app.i_limitLabel.Text = 'i_limit';
            app.out_limitLabel = uilabel(app.PIDAdjustmentPanel);
            app.out_limitLabel.Text = 'out_limit';
            app.SetPIDButton = uibutton(app.PIDAdjustmentPanel, 'push');
            app.SetPIDButton.ButtonPushedFcn = createCallbackFcn(app, @SetPIDButtonPushed, true);
            app.SetPIDButton.Text = 'Set';
            app.EchoPIDButton = uibutton(app.PIDAdjustmentPanel, 'push');
            app.EchoPIDButton.ButtonPushedFcn = createCallbackFcn(app, @EchoPIDButtonPushed, true);
            app.EchoPIDButton.Text = 'Echo All';
            app.FreezeButton = uibutton(app.MetaTerminal, 'state');
            app.FreezeButton.Text = 'Freeze';
            app.PortDropDownLabel = uilabel(app.MetaTerminal);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.MetaTerminal);
            app.PortDropDown.Items = {'/dev/cu.SLAB_USBtoUART', '/dev/cu.HC-05-DevB', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.PortDropDown.Value = '/dev/cu.SLAB_USBtoUART';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Meta_Terminal_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MetaTerminal)

            % Execute the startup function
            runStartupFcn(app, @Meta_Terminal_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetaTerminal)
        end
    end
end